classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ECGApp                        matlab.ui.Figure
        ResetviewButton               matlab.ui.control.Button
        Q_R_SButton                   matlab.ui.control.Button
        EnterButton                   matlab.ui.control.Button
        WindowstartEditFieldLabel     matlab.ui.control.Label
        WindowstartEditField          matlab.ui.control.NumericEditField
        WindowendEditFieldLabel       matlab.ui.control.Label
        WindowendEditField            matlab.ui.control.NumericEditField
        WelcometoECGApplicationLabel  matlab.ui.control.Label
        SpectogramButton              matlab.ui.control.Button
        LoadSignalButton              matlab.ui.control.Button
        info                          matlab.ui.control.TextArea
        TachycadiaBradicardiaButton   matlab.ui.control.Button
        EctopicbeatsButton            matlab.ui.control.Button
        HeartBeatGaugeLabel           matlab.ui.control.Label
        HeartBeatGauge                matlab.ui.control.SemicircularGauge
        UIAxes                        matlab.ui.control.UIAxes
    end

    properties (Access = public)
        data;
        smw;
        Fs;
        Ts;
        N;
        time_axis;  
        Q_locs;
        S_locs;
        R_locs;
        window_start = 1;
        window_end = 4 ;
        text= 'please load the signal';
        Delta;
        btm ;
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.info.Value = app.text;
        end

        % Value changed function: WindowstartEditField
        function WindowstartEditFieldValueChanged(app, event)
            app.window_start = app.WindowstartEditField.Value;
        end

        % Button pushed function: LoadSignalButton
        function LoadSignalButtonPushed(app, event)
            app.UIAxes.cla;
            [app.data,app.Fs,app.Ts,app.N,app.time_axis] = charger();
            plot(app.UIAxes,app.time_axis(app.Fs:4*app.Fs),app.data(app.Fs:4*app.Fs));
            app.text = 'signal loaded';
            app.info.Value = app.text;
        end

        % Value changed function: WindowendEditField
        function WindowendEditFieldValueChanged(app, event)
            app.window_end= app.WindowendEditField.Value;
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            app.UIAxes.cla;
            app.Q_locs_sec=app.Q_locs/app.Fs;
            app.S_locs_sec=app.S_locs/app.Fs;
            [index_start, nb_peaks, data_window] = window(app.data,app.Fs,app.window_start,app.window_end,app.Q_locs_sec);
            plot(app.UIAxes,app.time_axis(app.window_start*app.Fs:app.window_end*app.Fs),data_window); 
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.Q_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.Q_locs(index_start:index_start+nb_peaks-1)),'*')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.S_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.S_locs(index_start:index_start+nb_peaks-1)),'*')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.R_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.R_locs(index_start:index_start+nb_peaks-1)),'O')            
        end

        % Button pushed function: Q_R_SButton
        function Q_R_SButtonPushed(app, event)
            app.text = 'waiting for QRS display';
            app.info.Value = app.text; 
            m=150;
            app.smw = Smw(app.data,app.Fs);
            [app.R_locs,app.Q_locs,app.S_locs] = R_Q_S_peaks(app.data,app.smw);
            Q_locs_sec=app.Q_locs/app.Fs;
            S_locs_sec=app.S_locs/app.Fs;
            R_locs_sec=app.R_locs/app.Fs;
            [index_start, nb_peaks, data_window] = window(app.data,app.Fs,1,4,Q_locs_sec);
            plot(app.UIAxes,app.time_axis(1*app.Fs:4*app.Fs),data_window); 
            hold(app.UIAxes,'on');
            plot(app.UIAxes,Q_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.Q_locs(index_start:index_start+nb_peaks-1)),'*')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,S_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.S_locs(index_start:index_start+nb_peaks-1)),'*')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,R_locs_sec(index_start:index_start+nb_peaks-1),app.data(app.R_locs(index_start:index_start+nb_peaks-1)),'O')
            hold(app.UIAxes,'on');
            plot(app.UIAxes,app.time_axis(app.Fs:4*app.Fs),app.smw(app.Fs:4*app.Fs));  
            app.text = 'QRS waves displayed';
            app.info.Value = app.text;
        end

        % Button pushed function: ResetviewButton
        function ResetviewButtonPushed(app, event)
             app.UIAxes.cla;
             app.text = ' ';
             app.info.Value = app.text;
        end

        % Button pushed function: TachycadiaBradicardiaButton
        function TachycadiaBradicardiaButtonPushed(app, event)
            R_locs_sec = app.R_locs/app.Fs;
            [app.Delta, Delta_barre,Arrythmie]= Arrythmia(R_locs_sec);
            if (Arrythmie > 0.6)
               if (Arrythmie < 1)
                   app.text = 'Tachycardia and Bradicadia test is negative ';
                   app.info.Value = app.text;
               else
                   app.text = 'Tachycardia test is positive ';
                   app.info.Value = app.text;
               end
            else (Arrythmie < 0.6)
                   app.text = 'Bradichardia test is positive ';
                   app.info.Value = app.text;
            end
        end

        % Button pushed function: EctopicbeatsButton
        function EctopicbeatsButtonPushed(app, event)
            [patient_malady,ectopic_malady]= Ectopic_beat(app.Delta);
            if (patient_malady == 0)
                app.text = 'Ectopic beat test is negative ';
                app.info.Value = app.text;
            else
                app.text = 'Ectopic beat test is positive ';
                app.info.Value = app.text;
            end
        end

        % Button pushed function: SpectogramButton
        function SpectogramButtonPushed(app, event)
            spectrogram(app.data);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ECGApp = uifigure;
            app.ECGApp.Name = 'UI Figure';
            app.ResetviewButton = uibutton(app.ECGApp, 'push');
            app.ResetviewButton.ButtonPushedFcn = createCallbackFcn(app, @ResetviewButtonPushed, true);
            app.ResetviewButton.Text = 'Reset view';
            app.Q_R_SButton = uibutton(app.ECGApp, 'push');
            app.Q_R_SButton.ButtonPushedFcn = createCallbackFcn(app, @Q_R_SButtonPushed, true);
            app.Q_R_SButton.Text = 'Q_R_S';
            app.EnterButton = uibutton(app.ECGApp, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.WindowstartEditFieldLabel = uilabel(app.ECGApp);
            app.WindowstartEditFieldLabel.Text = 'Window start';
            app.WindowstartEditField = uieditfield(app.ECGApp, 'numeric');
            app.WindowstartEditField.ValueDisplayFormat = '%.0f';
            app.WindowstartEditField.ValueChangedFcn = createCallbackFcn(app, @WindowstartEditFieldValueChanged, true);
            app.WindowstartEditField.Value = 1;
            app.WindowendEditFieldLabel = uilabel(app.ECGApp);
            app.WindowendEditFieldLabel.Text = 'Window end';
            app.WindowendEditField = uieditfield(app.ECGApp, 'numeric');
            app.WindowendEditField.ValueDisplayFormat = '%.0f';
            app.WindowendEditField.ValueChangedFcn = createCallbackFcn(app, @WindowendEditFieldValueChanged, true);
            app.WindowendEditField.Value = 4;
            app.WelcometoECGApplicationLabel = uilabel(app.ECGApp);
            app.WelcometoECGApplicationLabel.Text = 'Welcome to ECG Application';
            app.SpectogramButton = uibutton(app.ECGApp, 'push');
            app.SpectogramButton.ButtonPushedFcn = createCallbackFcn(app, @SpectogramButtonPushed, true);
            app.SpectogramButton.Text = 'Spectogram';
            app.LoadSignalButton = uibutton(app.ECGApp, 'push');
            app.LoadSignalButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSignalButtonPushed, true);
            app.LoadSignalButton.Text = 'Load Signal';
            app.info = uitextarea(app.ECGApp);
            app.info.Editable = 'off';
            app.TachycadiaBradicardiaButton = uibutton(app.ECGApp, 'push');
            app.TachycadiaBradicardiaButton.ButtonPushedFcn = createCallbackFcn(app, @TachycadiaBradicardiaButtonPushed, true);
            app.TachycadiaBradicardiaButton.Text = 'Tachycadia/Bradicardia';
            app.EctopicbeatsButton = uibutton(app.ECGApp, 'push');
            app.EctopicbeatsButton.ButtonPushedFcn = createCallbackFcn(app, @EctopicbeatsButtonPushed, true);
            app.EctopicbeatsButton.Text = 'Ectopic beats';
            app.HeartBeatGaugeLabel = uilabel(app.ECGApp);
            app.HeartBeatGaugeLabel.Text = 'Heart Beat';
            app.HeartBeatGauge = uigauge(app.ECGApp, 'semicircular');
            app.HeartBeatGauge.Limits = [50 120];
            app.HeartBeatGauge.Value = 70;
            app.UIAxes = uiaxes(app.ECGApp);
            title(app.UIAxes, 'ElectroCardioGram Signal')
            xlabel(app.UIAxes, 'Tilme axis')
            ylabel(app.UIAxes, 'Signal')
        end
    end

    methods (Access = public)

        % Construct app
        function app = application

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ECGApp)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ECGApp)
        end
    end
end